classdef HMI_proyecto_intermedio_robotica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UNIVERSIDADNACIONALDECOLOMBIALabel  matlab.ui.control.Label
        SEDEBOGOTALabel          matlab.ui.control.Label
        Robotica2020IILabel      matlab.ui.control.Label
        Image_logo               matlab.ui.control.Image
        lbl_estado               matlab.ui.control.Label
        img_estado               matlab.ui.control.Image
        btn_start                matlab.ui.control.Button
        btn_stop                 matlab.ui.control.Button
        img_grupo                matlab.ui.container.TabGroup
        img_normal               matlab.ui.container.Tab
        img_deteccion            matlab.ui.container.Tab
        grupo_posicion           matlab.ui.container.TabGroup
        leer_posicion            matlab.ui.container.Tab
        XLabel                   matlab.ui.control.Label
        pos_x_tool               matlab.ui.control.Label
        YLabel                   matlab.ui.control.Label
        POSICIONDELTOOLLabel     matlab.ui.control.Label
        pos_y_tool               matlab.ui.control.Label
        ZLabel                   matlab.ui.control.Label
        pos_z_tool               matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        pos_phi_tool             matlab.ui.control.Label
        control_posicion         matlab.ui.container.Tab
        grupo_configuracion      matlab.ui.container.TabGroup
        leer_configuracion       matlab.ui.container.Tab
        Q1Label                  matlab.ui.control.Label
        CONFIGURACIONLabel       matlab.ui.control.Label
        config_q1                matlab.ui.control.Label
        Q2Label                  matlab.ui.control.Label
        config_q2                matlab.ui.control.Label
        Q3Label                  matlab.ui.control.Label
        config_q3                matlab.ui.control.Label
        Q4Label                  matlab.ui.control.Label
        config_q4                matlab.ui.control.Label
        controlar_configuracion  matlab.ui.container.Tab
        Q1Label_2                matlab.ui.control.Label
        CONFIGURACIONLabel_2     matlab.ui.control.Label
        Q2Label_2                matlab.ui.control.Label
        Q3Label_2                matlab.ui.control.Label
        Q4Label_2                matlab.ui.control.Label
        btn_enviar_R_izquierdo   matlab.ui.control.Button
        input_q1_R_z             matlab.ui.control.NumericEditField
        input_q2_R_z             matlab.ui.control.NumericEditField
        input_q3_R_z             matlab.ui.control.NumericEditField
        input_q4_R_z             matlab.ui.control.NumericEditField
        Controlar_R_DerechoTab   matlab.ui.container.Tab
        CONFIGURACIONLabel_3     matlab.ui.control.Label
        Q1Label_3                matlab.ui.control.Label
        input_q1_R_d             matlab.ui.control.NumericEditField
        Q2Label_3                matlab.ui.control.Label
        input_q2_R_d             matlab.ui.control.NumericEditField
        Q3Label_3                matlab.ui.control.Label
        input_q3_R_d             matlab.ui.control.NumericEditField
        Q4Label_3                matlab.ui.control.Label
        input_q4_R_d             matlab.ui.control.NumericEditField
        btn_enviar_R_derecho     matlab.ui.control.Button
    end

    
    properties (Access = public)
        publicador_robot_izquierda_joint_1; % Publicador robot izquerdo articulacion 1.
        publicador_robot_izquierda_joint_2; % Publicador robot izquerdo articulacion 2.
        publicador_robot_izquierda_joint_3; % Publicador robot izquerdo articulacion 3.
        publicador_robot_izquierda_joint_4; % Publicador robot izquerdo articulacion 4.
        publicador_robot_izquierda_gripper; % Publicador robot izquerdo gripper.
        robot_izquierda_articulacion_1;     % mensaje de la articulacion 1 del robot izquierdo.
        robot_izquierda_articulacion_2;     % mensaje de la articulacion 2 del robot izquierdo.
        robot_izquierda_articulacion_3;     % mensaje de la articulacion 3 del robot izquierdo.
        robot_izquierda_articulacion_4;     % mensaje de la articulacion 4 del robot izquierdo.
        robot_izquierda_gripper;            % Mensaje del gripper del robot izquierdo.
        publicador_robot_derecha_joint_1;   % Publicador robot derecha articulacion 1.
        publicador_robot_derecha_joint_2;   % Publicador robot derecha articulacion 2.
        publicador_robot_derecha_joint_3;   % Publicador robot derecha articulacion 3.
        publicador_robot_derecha_joint_4;   % Publicador robot derecha articulacion 4.
        publicador_robot_derecha_gripper;   % Publicador al gripper del robot derecho.
        robot_derecha_articulacion_1;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_2;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_3;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_articulacion_4;       % Mensaje de la articulacion 1 del robot derecho.
        robot_derecha_gripper;              % Mensaje del gripper del robot derecho.
        
    end
    
    methods (Access = public)        
        function iniciar_publicadores_robot_izquierdo(app)
            % Creacion de los publicadores del robot izquierdo:
            app.publicador_robot_izquierda_joint_1 = rospublisher('/robot_izquierda/joint1_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 1 creado...');
            
            app.publicador_robot_izquierda_joint_2 = rospublisher('/robot_izquierda/joint2_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 2 creado...');
            
            app.publicador_robot_izquierda_joint_3 = rospublisher('/robot_izquierda/joint3_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 3 creado...');
            
            app.publicador_robot_izquierda_joint_4 = rospublisher('/robot_izquierda/joint4_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot izquierdo de la articulación 4 creado...');
            
            app.publicador_robot_izquierda_gripper = rospublisher('/robot_izquierda/gripper_position_controller/command','std_msgs/Float64MultiArray');
            pause(1);
            disp('Publicador del gripper del robot izquierdo creado...'); 
            
            %Creación del mensaje de articulaciones del robot de la izquierda:    
            app.robot_izquierda_articulacion_1 = rosmessage(app.publicador_robot_izquierda_joint_1);
            app.robot_izquierda_articulacion_2 = rosmessage(app.publicador_robot_izquierda_joint_2);
            app.robot_izquierda_articulacion_3 = rosmessage(app.publicador_robot_izquierda_joint_3);
            app.robot_izquierda_articulacion_4 = rosmessage(app.publicador_robot_izquierda_joint_4);
            app.robot_izquierda_gripper = rosmessage(app.publicador_robot_izquierda_gripper);
            
        end
        
        function iniciar_publicadores_robot_derecho(app)
            % Creacion de los publicadores del robot derecho:           
            app.publicador_robot_derecha_joint_1 = rospublisher('/robot_derecha/joint1_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 1 creado...');
            
            app.publicador_robot_derecha_joint_2 = rospublisher('/robot_derecha/joint2_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 2 creado...');
            
            app.publicador_robot_derecha_joint_3 = rospublisher('/robot_derecha/joint3_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 3 creado...');
            
            app.publicador_robot_derecha_joint_4 = rospublisher('/robot_derecha/joint4_position_controller/command','std_msgs/Float64');
            pause(1);
            disp('Publicador del robot derecha de la articulación 4 creado...');
            
            app.publicador_robot_derecha_gripper = rospublisher('/robot_derecha/gripper_position_controller/command','std_msgs/Float64MultiArray');
            pause(1);
            disp('Publicador del gripper del robot derecha creado...');
            % Creacion de los mensajes del robot derecho:
            app.robot_derecha_articulacion_1 = rosmessage(app.publicador_robot_derecha_joint_1);
            app.robot_derecha_articulacion_2 = rosmessage(app.publicador_robot_derecha_joint_2);
            app.robot_derecha_articulacion_3 = rosmessage(app.publicador_robot_derecha_joint_3);
            app.robot_derecha_articulacion_4 = rosmessage(app.publicador_robot_derecha_joint_4);
            app.robot_derecha_gripper = rosmessage(app.publicador_robot_derecha_gripper);           
            
        end
        
        function enviar_datos_articulaciones_robot_izquierdo(app,q1,q2,q3,q4)
            
            app.robot_izquierda_articulacion_1.Data = deg2rad(q1);
            app.robot_izquierda_articulacion_2.Data = deg2rad(q2);
            app.robot_izquierda_articulacion_3.Data = deg2rad(q3);
            app.robot_izquierda_articulacion_4.Data = deg2rad(q4);
            send(app.publicador_robot_izquierda_joint_1,app.robot_izquierda_articulacion_1);
            send(app.publicador_robot_izquierda_joint_2,app.robot_izquierda_articulacion_2);
            send(app.publicador_robot_izquierda_joint_3,app.robot_izquierda_articulacion_3);
            send(app.publicador_robot_izquierda_joint_4,app.robot_izquierda_articulacion_4);
            
        end
        
        function enviar_datos_articulaciones_robot_derecho(app,q1,q2,q3,q4)
            app.robot_derecha_articulacion_1.Data = deg2rad(q1);
            app.robot_derecha_articulacion_2.Data = deg2rad(q2);
            app.robot_derecha_articulacion_3.Data = deg2rad(q3);
            app.robot_derecha_articulacion_4.Data = deg2rad(q4);
            send(app.publicador_robot_derecha_joint_1,app.robot_derecha_articulacion_1);
            send(app.publicador_robot_derecha_joint_2,app.robot_derecha_articulacion_2);
            send(app.publicador_robot_derecha_joint_3,app.robot_derecha_articulacion_3);
            send(app.publicador_robot_derecha_joint_4,app.robot_derecha_articulacion_4);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try                                                                                 % Intenta inicializar el nodo de matlab. Si no falla:
                app.lbl_estado.Text = 'Estado: Inicializando todo.';
                startup_rvc;
                waitbar(0.15,'Inicializando el nodo de matlab, por favor espere....');          % Informa al usuario del inicio del nodo de matlab.
                
                rosinit;                                                                        % Inicializa el nodo de matlab en ros.
                waitbar(0.25,'Inicializando Publicador robot izquierdo, por favor espere....'); % Informa al usuario del inicio del nodo de matlab.
                
                app.iniciar_publicadores_robot_izquierdo();                             % Funcion que inicializa los publicadores y mensajes del robot izquierdo.
                waitbar(0.25,'Inicializando Publicador robot derecho, por favor espere....'); % Informa al usuario del inicio del nodo de matlab.
                
                app.iniciar_publicadores_robot_derecho();
                waitbar(0.75,'ya casi terminamos, por favor espere....');               % Informa al usuario del inicio del nodo de matlab.
                
                waitbar(1,'Inicializaciones finalizadas....');                                     % Informa al usuario del inicio del nodo de matlab.                
                close all;                                                              % Cierra todas las ventanas de Informacion.
                app.img_estado.ImageSource = 'exito.png';                               % Cambia la imagen de estado a exito.png.
                app.lbl_estado.Text = 'Estado: Listo para trabajar!';                           % Cambia el lbl de estado a Nodo creado.
                
            catch                                                                  % Si falla en la cracion del nodo:
                close all;
                app.img_estado.ImageSource = 'warning.png';                           % Cambia la imagen de estado a warning.png.
                app.lbl_estado.Text = 'Estado: Algo anda mal!';               % Cambia el lbl de estado a El nodo no fue creado.
            end
        end

        % Button pushed function: btn_start
        function btn_startButtonPushed(app, event)
                        
        end

        % Button pushed function: btn_stop
        function btn_stopButtonPushed(app, event)
            close all;
            rosshutdown;                                    % Apaga el nodo de matlab.
            app.img_estado.ImageSource = 'en_espera.png';   % Cambia la imagen de estado a en espera.
            app.lbl_estado.Text = 'En espera!';             % Cambia el lbl de estado a En espera.
        end

        % Button pushed function: btn_enviar_R_izquierdo
        function btn_enviar_R_izquierdoButtonPushed(app, event)
           app.enviar_datos_articulaciones_robot_izquierdo(app.input_q1_R_z.Value,app.input_q2_R_z.Value,app.input_q3_R_z.Value,app.input_q4_R_z.Value);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end

        % Button pushed function: btn_enviar_R_derecho
        function btn_enviar_R_derechoButtonPushed(app, event)
            app.enviar_datos_articulaciones_robot_derecho(app.input_q1_R_d.Value,app.input_q2_R_d.Value,app.input_q3_R_d.Value,app.input_q4_R_d.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UNIVERSIDADNACIONALDECOLOMBIALabel = uilabel(app.UIFigure);
            app.UNIVERSIDADNACIONALDECOLOMBIALabel.Text = 'UNIVERSIDAD NACIONAL DE COLOMBIA';
            app.SEDEBOGOTALabel = uilabel(app.UIFigure);
            app.SEDEBOGOTALabel.Text = 'SEDE BOGOTA';
            app.Robotica2020IILabel = uilabel(app.UIFigure);
            app.Robotica2020IILabel.Text = 'Robotica 2020-II';
            app.Image_logo = uiimage(app.UIFigure);
            app.Image_logo.ImageSource = 'logou.png';
            app.lbl_estado = uilabel(app.UIFigure);
            app.lbl_estado.Text = 'Estado: En espera';
            app.img_estado = uiimage(app.UIFigure);
            app.img_estado.ImageSource = 'en_espera.png';
            app.btn_start = uibutton(app.UIFigure, 'push');
            app.btn_start.ButtonPushedFcn = createCallbackFcn(app, @btn_startButtonPushed, true);
            app.btn_start.Icon = 'start.png';
            app.btn_start.Tooltip = {'Pulse aqui para iniciar el proceso'};
            app.btn_start.Text = '';
            app.btn_stop = uibutton(app.UIFigure, 'push');
            app.btn_stop.ButtonPushedFcn = createCallbackFcn(app, @btn_stopButtonPushed, true);
            app.btn_stop.Icon = 'stop.jpg';
            app.btn_stop.Tooltip = {'Pulse aqui para detener el proceso.'};
            app.btn_stop.Text = '';
            app.img_grupo = uitabgroup(app.UIFigure);
            app.img_normal = uitab(app.img_grupo);
            app.img_normal.Title = 'Normal';
            app.img_deteccion = uitab(app.img_grupo);
            app.img_deteccion.Title = 'Deteccion';
            app.grupo_posicion = uitabgroup(app.UIFigure);
            app.leer_posicion = uitab(app.grupo_posicion);
            app.leer_posicion.Title = 'Leer';
            app.XLabel = uilabel(app.leer_posicion);
            app.XLabel.Text = 'X:';
            app.pos_x_tool = uilabel(app.leer_posicion);
            app.pos_x_tool.Text = '';
            app.YLabel = uilabel(app.leer_posicion);
            app.YLabel.Text = 'Y:';
            app.POSICIONDELTOOLLabel = uilabel(app.leer_posicion);
            app.POSICIONDELTOOLLabel.Text = 'POSICION DEL TOOL:';
            app.pos_y_tool = uilabel(app.leer_posicion);
            app.pos_y_tool.Text = '';
            app.ZLabel = uilabel(app.leer_posicion);
            app.ZLabel.Text = 'Z:';
            app.pos_z_tool = uilabel(app.leer_posicion);
            app.pos_z_tool.Text = '';
            app.Label = uilabel(app.leer_posicion);
            app.Label.Text = 'φ:';
            app.pos_phi_tool = uilabel(app.leer_posicion);
            app.pos_phi_tool.Text = '';
            app.control_posicion = uitab(app.grupo_posicion);
            app.control_posicion.Title = 'Controlar';
            app.grupo_configuracion = uitabgroup(app.UIFigure);
            app.leer_configuracion = uitab(app.grupo_configuracion);
            app.leer_configuracion.Title = 'Leer';
            app.Q1Label = uilabel(app.leer_configuracion);
            app.Q1Label.Text = 'Q1:';
            app.CONFIGURACIONLabel = uilabel(app.leer_configuracion);
            app.CONFIGURACIONLabel.Text = 'CONFIGURACION:';
            app.config_q1 = uilabel(app.leer_configuracion);
            app.config_q1.Text = '';
            app.Q2Label = uilabel(app.leer_configuracion);
            app.Q2Label.Text = 'Q2:';
            app.config_q2 = uilabel(app.leer_configuracion);
            app.config_q2.Text = '';
            app.Q3Label = uilabel(app.leer_configuracion);
            app.Q3Label.Text = 'Q3:';
            app.config_q3 = uilabel(app.leer_configuracion);
            app.config_q3.Text = '';
            app.Q4Label = uilabel(app.leer_configuracion);
            app.Q4Label.Text = 'Q4:';
            app.config_q4 = uilabel(app.leer_configuracion);
            app.config_q4.Text = '';
            app.controlar_configuracion = uitab(app.grupo_configuracion);
            app.controlar_configuracion.Title = 'Controlar_R_Izquierdo';
            app.Q1Label_2 = uilabel(app.controlar_configuracion);
            app.Q1Label_2.Text = 'Q1:';
            app.CONFIGURACIONLabel_2 = uilabel(app.controlar_configuracion);
            app.CONFIGURACIONLabel_2.Text = 'CONFIGURACION:';
            app.Q2Label_2 = uilabel(app.controlar_configuracion);
            app.Q2Label_2.Text = 'Q2:';
            app.Q3Label_2 = uilabel(app.controlar_configuracion);
            app.Q3Label_2.Text = 'Q3:';
            app.Q4Label_2 = uilabel(app.controlar_configuracion);
            app.Q4Label_2.Text = 'Q4:';
            app.btn_enviar_R_izquierdo = uibutton(app.controlar_configuracion, 'push');
            app.btn_enviar_R_izquierdo.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_R_izquierdoButtonPushed, true);
            app.btn_enviar_R_izquierdo.Icon = 'enviar.png';
            app.btn_enviar_R_izquierdo.Text = '';
            app.input_q1_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q2_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q3_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.input_q4_R_z = uieditfield(app.controlar_configuracion, 'numeric');
            app.Controlar_R_DerechoTab = uitab(app.grupo_configuracion);
            app.Controlar_R_DerechoTab.Title = 'Controlar_R_Derecho';
            app.CONFIGURACIONLabel_3 = uilabel(app.Controlar_R_DerechoTab);
            app.CONFIGURACIONLabel_3.Text = 'CONFIGURACION:';
            app.Q1Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q1Label_3.Text = 'Q1:';
            app.input_q1_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.Q2Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q2Label_3.Text = 'Q2:';
            app.input_q2_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.Q3Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q3Label_3.Text = 'Q3:';
            app.input_q3_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.Q4Label_3 = uilabel(app.Controlar_R_DerechoTab);
            app.Q4Label_3.Text = 'Q4:';
            app.input_q4_R_d = uieditfield(app.Controlar_R_DerechoTab, 'numeric');
            app.btn_enviar_R_derecho = uibutton(app.Controlar_R_DerechoTab, 'push');
            app.btn_enviar_R_derecho.ButtonPushedFcn = createCallbackFcn(app, @btn_enviar_R_derechoButtonPushed, true);
            app.btn_enviar_R_derecho.Icon = 'enviar.png';
            app.btn_enviar_R_derecho.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HMI_proyecto_intermedio_robotica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end